classdef distribution_calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CVoftotalnumberofcellsEditField  matlab.ui.control.NumericEditField
        CVoftotalnumberofcellsEditFieldLabel  matlab.ui.control.Label
        CVofFDcellpopulationEditField   matlab.ui.control.NumericEditField
        CVofFDcellpopulationEditFieldLabel  matlab.ui.control.Label
        CVofTAcellpopulationEditField   matlab.ui.control.NumericEditField
        CVofTAcellpopulationEditFieldLabel  matlab.ui.control.Label
        AveragenumberofFDcellsEditField  matlab.ui.control.NumericEditField
        AveragenumberofFDcellsEditFieldLabel  matlab.ui.control.Label
        AveragenumberofTAcellsEditField  matlab.ui.control.NumericEditField
        AveragenumberofTAcellsEditFieldLabel  matlab.ui.control.Label
        TAFDcorrelationcoefficientEditField  matlab.ui.control.NumericEditField
        TAFDcorrelationcoefficientEditFieldLabel  matlab.ui.control.Label
        TAcelldifferentiationrate1hEditField  matlab.ui.control.NumericEditField
        TAcelldifferentiationrate1hEditFieldLabel  matlab.ui.control.Label
        FDcellapoptosisrate1hEditField  matlab.ui.control.EditField
        FDcellapoptosisrate1hEditFieldLabel  matlab.ui.control.Label
        TAcelldivisionrate1hEditField   matlab.ui.control.EditField
        TAcelldivisionrate1hEditFieldLabel  matlab.ui.control.Label
        stemcelldivisionrate1hEditField  matlab.ui.control.EditField
        stemcelldivisionrate1hEditFieldLabel  matlab.ui.control.Label
        AveragetotalnumberofcellspercryptEditFieldLabel  matlab.ui.control.Label
        AveragetotalnumberofcellspercryptEditField  matlab.ui.control.NumericEditField
        NumberofstemcellsEditFieldLabel  matlab.ui.control.Label
        NumberofstemcellsEditField      matlab.ui.control.NumericEditField
        CalculateButton                 matlab.ui.control.StateButton
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CalculateButton
        function CalculateButtonValueChanged(app, event)
ntot = app.AveragetotalnumberofcellspercryptEditField.Value;
n0 = app.NumberofstemcellsEditField.Value;
r = str2num(app.stemcelldivisionrate1hEditField.Value);
l = str2num(app.TAcelldivisionrate1hEditField.Value);
g = str2num(app.FDcellapoptosisrate1hEditField.Value);

A1=(ntot/n0)*g-g-r;
A2=(ntot/n0)*g*l-g*l+g*r;
d=A2/A1;

% syms y
% d=solve((1+r/(y-l)+r*y/(g*(y-l)))*n0==ntot,y);
% d=double(d);

app.TAcelldifferentiationrate1hEditField.Value = d;

a=(d-l)/g;
B=d*l/g^2;
rr=r*n0/l;
b=l/g;

m1=r*n0/(d-l);
m2=d*m1/g;

app.AveragenumberofTAcellsEditField.Value =m1;
app.AveragenumberofFDcellsEditField.Value=m2;

s1=r*d*n0/(d-l)^2;
s2=rr*(B/a+B^2/(a^2*(a+1)));
s12=(l/(g+d-l))*s1;
stot=s1+s2+2*s12;
rho=s12/sqrt(s1*s2);

app.TAFDcorrelationcoefficientEditField.Value=rho;

CV1=sqrt(s1)*100/m1;
CV2=sqrt(s2)*100/m2;
CVtot=sqrt(stot)*100/ntot;

app.CVofTAcellpopulationEditField.Value=CV1;
app.CVofFDcellpopulationEditField.Value=CV2;
app.CVoftotalnumberofcellsEditField.Value=CVtot;

h = @(x)(B.*(1 - x)).^(a./2).*besseli(-a, 2.*(B.*(1 - x)).^(1./2));
q1=@(x)a+2*(B*(1-x)).^(1/2).*besseli(a+1,2*(B*(1-x)).^(1/2))./besseli(a,2*(B*(1-x)).^(1/2));
q2=@(x)-a+2*(B*(1-x)).^(1/2).*besseli(-a+1,2*(B*(1-x)).^(1/2))./besseli(-a,2*(B*(1-x)).^(1/2));

% probability-generating function for TA cell population
F_TA=@(x)((d-l)./(d-l.*x)).^rr;
% probability-generating function for FD cell population
F_FD = @(x)(gamma(1 - a).*h(x).*(1 - (a + q2(x))./(a + q1(x)))).^rr;
% probability-generating function for total cell population
F_tot = @(x)x.^(n0).*(gamma(1 - a).*h(x).*(1 - (a +2*b*(1-x)+ q2(x))./(a+2*b*(1-x) + q1(x)))).^rr;


% contour on complex plane
C = [1+1i -1+1i -1-1i 1-1i];

% calculation of probability of TA cell population
X1=zeros(1601,1);
P_TA=zeros(1601,1);
p_TA=zeros(1601,1);
parfor k=0:1600
    X1(k+1)=k;
    fun_TA=@(x)F_TA(x)./x.^(k+1);
    % calculation via Cauchy integration
    P_TA(k+1)=real((1/(2*pi*1i))*integral(fun_TA,(1+1i),(1+1i),'Waypoints',C));
    % calculation via explicit formula
    p_TA(k+1)=((gamma(sym(rr+k))/(factorial(sym(k))*gamma(rr)))*((d-l)/d)^rr*(l/d)^k);
end

% calculation of probability of FD and total cell population
X2=zeros(5001,1);
P_FD=zeros(5001,1);
P_tot=zeros(5001,1);
parfor k=0:5000
    X2(k+1)=k;
    fun_FD=@(x)F_FD(x)./x.^(k+1);
    fun_tot=@(x)F_tot(x)./x.^(k+1);
    P_FD(k+1)=real((1/(2*pi*1i))*integral(fun_FD,(1+1i),(1+1i),'Waypoints',C));
    P_tot(k+1)=real((1/(2*pi*1i))*integral(fun_tot,(1+1i),(1+1i),'Waypoints',C));
end

plot(app.UIAxes, X1,P_TA,'color','#BB8041','linewidth',4)

plot(app.UIAxes_2, X2,P_FD,'color','#9D8678','linewidth',4)

plot(app.UIAxes_3, X2,P_tot,'color','#c88080','linewidth',4)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Number of TA cells')
            ylabel(app.UIAxes, 'Distribution')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_2, 'Number of FD cells')
            ylabel(app.UIAxes_2, 'Distribution')
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_3, 'Total number of cells per crypt')
            ylabel(app.UIAxes_3, 'Distribution')
            zlabel(app.UIAxes_3, 'Z')
            app.CalculateButton = uibutton(app.UIFigure, 'state');
            app.CalculateButton.ValueChangedFcn = createCallbackFcn(app, @CalculateButtonValueChanged, true);
            app.CalculateButton.Text = 'Calculate';
            app.NumberofstemcellsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofstemcellsEditField.Limits = [0 Inf];
            app.NumberofstemcellsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofstemcellsEditFieldLabel.Text = 'Number of stem cells';
            app.AveragetotalnumberofcellspercryptEditField = uieditfield(app.UIFigure, 'numeric');
            app.AveragetotalnumberofcellspercryptEditField.Limits = [0 Inf];
            app.AveragetotalnumberofcellspercryptEditFieldLabel = uilabel(app.UIFigure);
            app.AveragetotalnumberofcellspercryptEditFieldLabel.Text = 'Average total number of cells per crypt';
            app.stemcelldivisionrate1hEditFieldLabel = uilabel(app.UIFigure);
            app.stemcelldivisionrate1hEditFieldLabel.Text = 'stem cell division rate (1/h)';
            app.stemcelldivisionrate1hEditField = uieditfield(app.UIFigure, 'text');
            app.stemcelldivisionrate1hEditField.Value = '0';
            app.TAcelldivisionrate1hEditFieldLabel = uilabel(app.UIFigure);
            app.TAcelldivisionrate1hEditFieldLabel.Text = 'TA cell division rate (1/h)';
            app.TAcelldivisionrate1hEditField = uieditfield(app.UIFigure, 'text');
            app.TAcelldivisionrate1hEditField.Value = '0';
            app.FDcellapoptosisrate1hEditFieldLabel = uilabel(app.UIFigure);
            app.FDcellapoptosisrate1hEditFieldLabel.Text = 'FD cell apoptosis rate (1/h)';
            app.FDcellapoptosisrate1hEditField = uieditfield(app.UIFigure, 'text');
            app.FDcellapoptosisrate1hEditField.Value = '0';
            app.TAcelldifferentiationrate1hEditFieldLabel = uilabel(app.UIFigure);
            app.TAcelldifferentiationrate1hEditFieldLabel.Text = 'TA cell differentiation rate (1/h)';
            app.TAcelldifferentiationrate1hEditField = uieditfield(app.UIFigure, 'numeric');
            app.TAFDcorrelationcoefficientEditFieldLabel = uilabel(app.UIFigure);
            app.TAFDcorrelationcoefficientEditFieldLabel.Text = 'TA, FD correlation coefficient';
            app.TAFDcorrelationcoefficientEditField = uieditfield(app.UIFigure, 'numeric');
            app.AveragenumberofTAcellsEditFieldLabel = uilabel(app.UIFigure);
            app.AveragenumberofTAcellsEditFieldLabel.Text = 'Average number of TA cells';
            app.AveragenumberofTAcellsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AveragenumberofFDcellsEditFieldLabel = uilabel(app.UIFigure);
            app.AveragenumberofFDcellsEditFieldLabel.Text = 'Average number of FD cells';
            app.AveragenumberofFDcellsEditField = uieditfield(app.UIFigure, 'numeric');
            app.CVofTAcellpopulationEditFieldLabel = uilabel(app.UIFigure);
            app.CVofTAcellpopulationEditFieldLabel.Text = 'CV% of TA cell population';
            app.CVofTAcellpopulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.CVofFDcellpopulationEditFieldLabel = uilabel(app.UIFigure);
            app.CVofFDcellpopulationEditFieldLabel.Text = 'CV% of FD cell population';
            app.CVofFDcellpopulationEditField = uieditfield(app.UIFigure, 'numeric');
            app.CVoftotalnumberofcellsEditFieldLabel = uilabel(app.UIFigure);
            app.CVoftotalnumberofcellsEditFieldLabel.Text = 'CV% of total number of cells';
            app.CVoftotalnumberofcellsEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = distribution_calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end